classdef UndistortionCrop < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LensCorrectCropUIFigure        matlab.ui.Figure
        lastROItouseSpinner            matlab.ui.control.Spinner
        lastROItouseSpinnerLabel       matlab.ui.control.Label
        selectMoviesButton             matlab.ui.control.Button
        selectareasofinterestButton    matlab.ui.control.Button
        selectCameraCalibrationButton  matlab.ui.control.Button
        progressEditField              matlab.ui.control.EditField
        progressLabel                  matlab.ui.control.Label
        areasofinterestEditField       matlab.ui.control.EditField
        cameracalibrationEditField     matlab.ui.control.EditField
        onlyextract1imagepermovieforareaofinterestselectionCheckBox  matlab.ui.control.CheckBox
        GoButton                       matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        workdir=''; % Description
        movfiles=[];
        cameraParams=[];
        fieldCoordinates=[];
        processsingfailed=false;
    end
    
    methods (Access = private)
        
        function [] = plotROIs(app)
            cla(app.UIAxes); 
            hold(app.UIAxes,'on')
            for i=1:app.lastROItouseSpinner.Value
                rectangle(app.UIAxes,'Position',[app.fieldCoordinates.BX(i)  app.fieldCoordinates.BY(i) app.fieldCoordinates.Width(i) app.fieldCoordinates.Height(i)]);
                text(app.UIAxes, app.fieldCoordinates.BX(i)+app.fieldCoordinates.Width(i)/2, app.fieldCoordinates.BY(i)+ app.fieldCoordinates.Height(i)/2, ['ROI #' num2str(i)]);
            end
            hold(app.UIAxes,'off')
        end
        
        function [] = readAndCheckROIfile(app)
            app.fieldCoordinates=readtable(app.areasofinterestEditField.Value,'Delimiter',',');
            for i=1:size(app.fieldCoordinates,1)
                a=app.fieldCoordinates.BY(i);
                a=app.fieldCoordinates.Height(i);
                a=app.fieldCoordinates.BX(i);
                a=app.fieldCoordinates.Width(i);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            try
                readAndCheckROIfile(app);
                app.lastROItouseSpinner.Limits=[1,size(app.fieldCoordinates,1)];
                app.lastROItouseSpinner.Value=size(app.fieldCoordinates,1);
                plotROIs(app);            catch
                app.progressEditField.Value='Not a valid ROI file ?';
                return;
            end

        end

        % Button pushed function: selectMoviesButton
        function selectMoviesButtonPushed(app, event)
            [movfiles2,workdirL] = uigetfile('*.mp4','Slect Movie File(s)','MultiSelect','on');
            drawnow;
            figure(app.LensCorrectCropUIFigure);
            if isa(movfiles2,'char')
                movfilesL{1}=movfiles2;
            else
                movfilesL=movfiles2;
            end
            app.workdir=workdirL;
            app.movfiles=movfilesL;
            app.GoButton.Enable=true;
        end

        % Button pushed function: GoButton
        function processMovies(app, event)
            
            app.GoButton.Enable=false;
            app.progressEditField.Value='';
            app.processsingfailed=false;
            try
                cp=load(app.cameracalibrationEditField.Value);
                cpname=fieldnames(cp);
                app.cameraParams=cp.(cpname{contains(cpname,'cameraPara')});
            catch
                app.progressEditField.Value='Not a calibration file ?';
                return;
            end

            try
               readAndCheckROIfile(app);
               plotROIs(app);
            catch
               app.progressEditField.Value='Not a valid ROI file ?';
               return;
            end
            
            saveUndistortedImage=app.onlyextract1imagepermovieforareaofinterestselectionCheckBox.Value;

            for kk=1:length(app.movfiles)

                fname=app.movfiles{kk};
                app.progressEditField.Value=(['processing ' fname ' ...']);

                outname=fname(1:end-4);

                v = VideoReader(fullfile(app.workdir,fname));
                totalFrames=floor(v.Duration*v.FrameRate);

                if ~saveUndistortedImage
                    for i=1:app.lastROItouseSpinner.Value
                        vout(i) = VideoWriter(fullfile(app.workdir,[outname num2str(i)]),'MPEG-4');
                        vout(i).FrameRate=60;
                        vout(i).Quality=99;
                        open(vout(i));
                    end
                end
                tic
                ni=0;
                while hasFrame(v)
                    ni=ni+1;
                    if mod(ni,10)==0
                        disp([fname ' Frame: ' num2str(ni)]);
                    end
                    I = readFrame(v);
                    try
                        [J,~] = undistortImage(I,app.cameraParams,'linear','OutputView','full');
                    catch
                        app.progressEditField.Value='Size mismatch! Wrong Camera Calibration?';
                        app.processsingfailed=true;
                        break;
                    end
                    if saveUndistortedImage
                        imwrite(J,fullfile(app.workdir,[fname(1:end-4) '_undistorted.png']));
                        break;
                    end
                    for i=1:app.lastROItouseSpinner.Value
                        writeVideo(vout(i),J(app.fieldCoordinates.BY(i)+1:app.fieldCoordinates.BY(i)+app.fieldCoordinates.Height(i),...
                            app.fieldCoordinates.BX(i)+1:app.fieldCoordinates.BX(i)+app.fieldCoordinates.Width(i),:));
                    end
                end
                if ~app.processsingfailed
                    app.progressEditField.Value=[num2str(totalFrames/toc) ' frames per second'];
                end
                if ~saveUndistortedImage
                    for i=1:app.lastROItouseSpinner.Value
                        close(vout(i));
                    end
                end
            end
            app.progressEditField.Value=[app.progressEditField.Value '            DONE'];
        end

        % Button pushed function: selectCameraCalibrationButton
        function selectCameraCalibrationButtonPushed(app, event)
            
            [ccName,ccDir]=uigetfile('*.mat','select camera calibration file');
            drawnow;
            figure(app.LensCorrectCropUIFigure);
            fname=fullfile(ccDir,ccName);
            app.cameracalibrationEditField.Value=fname;
            try
                cp=load(fname);
                cpname=fieldnames(cp);
                app.cameraParams=cp.(cpname{contains(cpname,'cameraPara')});
            catch
                app.progressEditField.Value='Not a calibration file ?';
                app.GoButton.Enable=false;
            end
        end

        % Button pushed function: selectareasofinterestButton
        function selectareasofinterestButtonPushed(app, event)
            [ROIName,ROIDir]=uigetfile('*.csv','select ROI/ Dish areas file');
            drawnow;
            figure(app.LensCorrectCropUIFigure); 
            fname=fullfile(ROIDir,ROIName);
            app.areasofinterestEditField.Value=fname;
            try
               readAndCheckROIfile(app);
               app.lastROItouseSpinner.Limits=[1,size(app.fieldCoordinates,1)];
               app.lastROItouseSpinner.Value=size(app.fieldCoordinates,1);
               plotROIs(app);
            catch
                app.progressEditField.Value='Not a valid ROI file ?';
                app.GoButton.Enable=false;
            end
        end

        % Value changed function: lastROItouseSpinner
        function lastROItouseSpinnerValueChanged(app, event)
            value = app.lastROItouseSpinner.Value;
            plotROIs(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LensCorrectCropUIFigure = uifigure('Visible', 'off');
            app.LensCorrectCropUIFigure.Name = 'LensCorrectCrop';
            app.UIAxes = uiaxes(app.LensCorrectCropUIFigure);
            title(app.UIAxes, 'Image Area')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YDir = 'reverse';
            app.GoButton = uibutton(app.LensCorrectCropUIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @processMovies, true);
            app.GoButton.Enable = 'off';
            app.GoButton.Text = 'Go';
            app.onlyextract1imagepermovieforareaofinterestselectionCheckBox = uicheckbox(app.LensCorrectCropUIFigure);
            app.onlyextract1imagepermovieforareaofinterestselectionCheckBox.Text = 'only extract 1 image per movie (for area of interest selection)';
            app.cameracalibrationEditField = uieditfield(app.LensCorrectCropUIFigure, 'text');
            app.cameracalibrationEditField.Editable = 'off';
            app.cameracalibrationEditField.Enable = 'off';
            app.cameracalibrationEditField.Value = 'B:\fs3-bifexchgrp\BIF_StaffSci\Robert\Mara\CameraCalibration\cameraParamsNew.mat';
            app.areasofinterestEditField = uieditfield(app.LensCorrectCropUIFigure, 'text');
            app.areasofinterestEditField.Editable = 'off';
            app.areasofinterestEditField.Enable = 'off';
            app.areasofinterestEditField.Value = 'B:\fs3-bifexchgrp\BIF_StaffSci\Robert\Mara\CameraCalibration\1015.csv';
            app.progressLabel = uilabel(app.LensCorrectCropUIFigure);
            app.progressLabel.Text = 'progress:';
            app.progressEditField = uieditfield(app.LensCorrectCropUIFigure, 'text');
            app.progressEditField.Editable = 'off';
            app.selectCameraCalibrationButton = uibutton(app.LensCorrectCropUIFigure, 'push');
            app.selectCameraCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @selectCameraCalibrationButtonPushed, true);
            app.selectCameraCalibrationButton.Text = 'select Camera Calibration';
            app.selectareasofinterestButton = uibutton(app.LensCorrectCropUIFigure, 'push');
            app.selectareasofinterestButton.ButtonPushedFcn = createCallbackFcn(app, @selectareasofinterestButtonPushed, true);
            app.selectareasofinterestButton.Text = 'select areas of interest';
            app.selectMoviesButton = uibutton(app.LensCorrectCropUIFigure, 'push');
            app.selectMoviesButton.ButtonPushedFcn = createCallbackFcn(app, @selectMoviesButtonPushed, true);
            app.selectMoviesButton.Text = 'select Movies';
            app.lastROItouseSpinnerLabel = uilabel(app.LensCorrectCropUIFigure);
            app.lastROItouseSpinnerLabel.Text = 'last ROI to use';
            app.lastROItouseSpinner = uispinner(app.LensCorrectCropUIFigure);
            app.lastROItouseSpinner.Limits = [1 6];
            app.lastROItouseSpinner.ValueChangedFcn = createCallbackFcn(app, @lastROItouseSpinnerValueChanged, true);
            app.lastROItouseSpinner.Value = 1;
            % Show the figure after all components are created
            app.LensCorrectCropUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UndistortionCrop

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.LensCorrectCropUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.LensCorrectCropUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LensCorrectCropUIFigure)
        end
    end
end